classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        netDropDown        matlab.ui.control.DropDown
        netDropDownLabel   matlab.ui.control.Label
        finishedLamp       matlab.ui.control.Lamp
        finishedLampLabel  matlab.ui.control.Label
        runningLamp        matlab.ui.control.Lamp
        runningLampLabel   matlab.ui.control.Label
        RunButton          matlab.ui.control.Button
        FilePath           matlab.ui.control.EditField
        Label              matlab.ui.control.Label
        BrowseButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
    % Otwiera okno dialogowe umożliwiające użytkownikowi wybór pliku zip
    [file, path] = uigetfile('*.zip');
    
    % Sprawdza, czy użytkownik wybrał plik
    if isequal(file, 0)
        app.FilePath.Value = 'nie wybrano pliku';
    else
        app.FilePath.Value = fullfile(path, file);
    end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    % Zmiana koloru lampki 'running' na zielony podczas działania algorytmu
    app.runningLamp.Color = 'green';

    try
        % Pobranie ścieżki do wybranego pliku zip
        zipFilePath = app.FilePath.Value;
        % Sprawdzenie, czy plik został wybrany, jeśli nie wyświetlany jest
        % komunikat błędu
        if strcmp(zipFilePath, 'nie wybrano pliku')
            uialert(app.UIFigure, 'proszę najpierw wybrać', 'File Error');
            return;
        end

        % Rozpakowanie wybranego pliku zip
        unzip(zipFilePath);
        % Pobranie informacji o zawartości bieżącego folderu
        folderInfo = dir();
        isDir = [folderInfo.isdir];
        % Pobranie nazw folderów
        folderNames = {folderInfo(isDir).name};
        % Usunięcie bieżących i nadrzędnych folderów ('.' i '..') z listy
        folderNames = folderNames(~ismember(folderNames, {'.', '..'}));
        % Sprawdzenie, czy są jakieś foldery; jeśli nie, wyświetlenie komunikatu o błędzie
        if isempty(folderNames)
            uialert(app.UIFigure, 'Niepoprawny folder po wypakowaniu', ...
                'Folder Error');
            return;
        end
        % Uzyskanie nazwy pierwszego folderu (zakładamy, że zawiera on obrazy)
        imageFolder = folderNames{1};

        % Utworzenie obiektu imageDatastore dla obrazów w rozpakowanym folderze
        imds = imageDatastore(imageFolder, ...
            'IncludeSubfolders', true, ...
            'LabelSource', 'foldernames');
        % Podzielenie zbioru danych na zbiór treningowy i walidacyjny (70% treningowy, 30% walidacyjny)
        [imdsTrain, imdsValidation] = splitEachLabel(imds, 0.7);

        % Wybór odpowiedniej sieci neuronowej na podstawie wyboru użytkownika z netDropDown
        selectedNet = app.netDropDown.Value;
        switch selectedNet
            case 'googlenet'
                net = googlenet;
            case 'resnet'
                net = resnet50;
            otherwise
                error('Unknown network selected');
        end

        % Analiza struktury wybranej sieci neuronowej
        analyzeNetwork(net);
        % Uzyskanie rozmiaru wejściowego dla sieci
        inputSize = net.Layers(1).InputSize;
        % Utworzenie grafu warstw na podstawie sieci
        lgraph = layerGraph(net);
        % Znalezienie warstw do zastąpienia w grafie warstw
        [learnableLayer, classLayer] = findLayersToReplace(lgraph);

        % Wyświetlenie zidentyfikowanych warstw do debugowania
        disp(learnableLayer);
        disp(classLayer);

        % Uzyskanie liczby klas na podstawie etykiet w zbiorze treningowym
        numClasses = numel(categories(imdsTrain.Labels));

        % Utworzenie nowej warstwy uczącej się odpowiedniej do liczby klas
        if isa(learnableLayer, 'nnet.cnn.layer.FullyConnectedLayer')
            newLearnableLayer = fullyConnectedLayer(numClasses, ...
                'Name', 'new_fc', ...
                'WeightLearnRateFactor', 10, ...
                'BiasLearnRateFactor', 10);
        elseif isa(learnableLayer, 'nnet.cnn.layer.Convolution2DLayer')
            newLearnableLayer = convolution2dLayer(1, numClasses, ...
                'Name', 'new_conv', ...
                'WeightLearnRateFactor', 10, ...
                'BiasLearnRateFactor', 10);
        else
            error('Unsupported learnable layer type');
        end

        % Zastąpienie warstwy uczącej się w grafie nową warstwą
        lgraph = replaceLayer(lgraph, learnableLayer.Name, newLearnableLayer);
        % Utworzenie nowej warstwy klasyfikacyjnej
        newClassLayer = classificationLayer('Name', 'new_classoutput');
        % Zastąpienie warstwy klasyfikacyjnej w grafie nową warstwą
        lgraph = replaceLayer(lgraph, classLayer.Name, newClassLayer);

        % Wizualizacja zaktualizowanego grafu warstw
        figure('Units', 'normalized', 'Position', [0.3 0.3 0.4 0.4]);
        plot(lgraph);
        ylim([0, 10]);
        layers = lgraph.Layers;
        connections = lgraph.Connections;

        % Zamrożenie wag pierwszych kilku warstw co
        % zapobiega ich uczeniu się
        layers(1:10) = freezeWeights(layers(1:10));
        lgraph = createLgraphUsingConnections(layers, connections);

        % Ustawienia augmentacji danych obrazowych 
        % (losowe przekształcenia obrazów)
        pixelRange = [-30 30];
        scaleRange = [0.9 1.1];
        imageAugmenter = imageDataAugmenter( ...
            'RandXReflection', true, ...
            'RandXTranslation', pixelRange, ...
            'RandYTranslation', pixelRange, ...
            'RandXScale', scaleRange, ...
            'RandYScale', scaleRange);
        % Utworzenie obiektów augmentedImageDatastore dla zbioru 
        % treningowego i walidacyjnego
        augimdsTrain = augmentedImageDatastore(inputSize(1:2), ...
            imdsTrain, ...
            'DataAugmentation', imageAugmenter);
        augimdsValidation = augmentedImageDatastore(inputSize(1:2), ...
            imdsValidation);

        % Ustawienia opcji trenowania sieci neuronowej
        miniBatchSize = 10;
        valFrequency = floor(numel(augimdsTrain.Files) / miniBatchSize);
        options = trainingOptions('sgdm', ...
            'MiniBatchSize', miniBatchSize, ...
            'MaxEpochs', 6, ...
            'InitialLearnRate', 3e-4, ...
            'Shuffle', 'every-epoch', ...
            'ValidationData', augimdsValidation, ...
            'ValidationFrequency', valFrequency, ...
            'Verbose', false, ...
            'Plots', 'training-progress');

        % Trenowanie sieci neuronowej na zbiorze treningowym z użyciem 
        % zdefiniowanych opcji
        net = trainNetwork(augimdsTrain, lgraph, options);
        % Klasyfikacja obrazów w zbiorze walidacyjnym i obliczenie dokładności
        [YPred, probs] = classify(net, augimdsValidation);
        accuracy = mean(YPred == imdsValidation.Labels);

        % Losowe wybranie kilku obrazów walidacyjnych i wyświetlenie 
        % ich z przewidywanymi etykietami
        idx = randperm(numel(imdsValidation.Files), 4);
        figure
        for i = 1:4
            subplot(2, 2, i)
            I = readimage(imdsValidation, idx(i));
            imshow(I)
            label = YPred(idx(i));
            title(string(label) + ", " + ...
                num2str(100 * max(probs(idx(i), :)), 3) + "%");
        end
        
        % Po zakończeniu trenowania zmiana koloru lampki finished na zielony
        app.finishedLamp.Color = 'green';
    catch ME
        % Obsługa błędów, jeśli wystąpią w trakcie procesu
        disp(ME.message);
        app.runningLamp.Color = 'red'; % Wskazanie błędu
    end

    % Resetowanie koloru lampki running na czarny aby wskazać zakończenie
    % działania algorytmu
    app.runningLamp.Color = 'black';

     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FilePath = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.runningLampLabel = uilabel(app.UIFigure);
            app.runningLampLabel.Text = 'running';
            app.runningLamp = uilamp(app.UIFigure);
            app.finishedLampLabel = uilabel(app.UIFigure);
            app.finishedLampLabel.Text = 'finished';
            app.finishedLamp = uilamp(app.UIFigure);
            app.netDropDownLabel = uilabel(app.UIFigure);
            app.netDropDownLabel.Text = 'net = ';
            app.netDropDown = uidropdown(app.UIFigure);
            app.netDropDown.Items = {'googlenet', 'resnet'};
            app.netDropDown.Value = 'googlenet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end